classdef Wealther_Balloon_Simulation_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Impact_Velocity_Print           matlab.ui.control.NumericEditField
        BalloonWeightLabel_21           matlab.ui.control.Label
        FeetpersecondLabel              matlab.ui.control.Label
        MeterspersecondLabel            matlab.ui.control.Label
        MaxTimeError_Label              matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        SimulationCompletedLabel        matlab.ui.control.Label
        PoundsLabel_2                   matlab.ui.control.Label
        PoundsLabel                     matlab.ui.control.Label
        Feet2Label                      matlab.ui.control.Label
        FeetLabel_6                     matlab.ui.control.Label
        AtmospheresLabel_2              matlab.ui.control.Label
        FahrenheitLabel_2               matlab.ui.control.Label
        FeetLabel_4                     matlab.ui.control.Label
        FeetLabel_3                     matlab.ui.control.Label
        FeetLabel_2                     matlab.ui.control.Label
        AtmospheresLabel                matlab.ui.control.Label
        FahrenheitLabel                 matlab.ui.control.Label
        FeetLabel                       matlab.ui.control.Label
        Hidden_Flipper                  matlab.ui.control.NumericEditField
        BalloonWeightLabel_20           matlab.ui.control.Label
        Change_Between_SI_and_Imperial_Units_Button  matlab.ui.control.Button
        Max_Altitude_Print              matlab.ui.control.NumericEditField
        BalloonWeightLabel_19           matlab.ui.control.Label
        MetersLabel_6                   matlab.ui.control.Label
        Lowest_Pressure_Print           matlab.ui.control.NumericEditField
        BalloonWeightLabel_18           matlab.ui.control.Label
        PascalsLabel_2                  matlab.ui.control.Label
        Coldest_Temperature_Print       matlab.ui.control.NumericEditField
        BalloonWeightLabel_17           matlab.ui.control.Label
        KelvinLabel_2                   matlab.ui.control.Label
        Balloon_Max_Diameter_Print      matlab.ui.control.NumericEditField
        BalloonWeightLabel_16           matlab.ui.control.Label
        MetersLabel_5                   matlab.ui.control.Label
        Total_Time_Print                matlab.ui.control.NumericEditField
        BalloonWeightLabel_15           matlab.ui.control.Label
        HoursLabel_3                    matlab.ui.control.Label
        Time_to_Descend_Print           matlab.ui.control.NumericEditField
        BalloonWeightLabel_14           matlab.ui.control.Label
        HoursLabel_2                    matlab.ui.control.Label
        Time_to_Apogee_Print            matlab.ui.control.NumericEditField
        BalloonWeightLabel_13           matlab.ui.control.Label
        HoursLabel                      matlab.ui.control.Label
        MiscellaneousParametersLabel    matlab.ui.control.Label
        ImportantValuesFromSimulationLabel  matlab.ui.control.Label
        PayloadParametersLabel          matlab.ui.control.Label
        GroundReadingsLabel             matlab.ui.control.Label
        BalloonParametersLabel          matlab.ui.control.Label
        Time_Step_Input                 matlab.ui.control.NumericEditField
        BalloonWeightLabel_12           matlab.ui.control.Label
        SecondsLabel                    matlab.ui.control.Label
        Parachute_Drag_Coefficient_Input  matlab.ui.control.NumericEditField
        BalloonWeightLabel_11           matlab.ui.control.Label
        UnitlessLabel                   matlab.ui.control.Label
        Parachute_Area_Input            matlab.ui.control.NumericEditField
        BalloonWeightLabel_10           matlab.ui.control.Label
        Meters2Label                    matlab.ui.control.Label
        Payload_Mass_Input              matlab.ui.control.NumericEditField
        BalloonWeightLabel_9            matlab.ui.control.Label
        KilogramsLabel_2                matlab.ui.control.Label
        Ground_Altitude_Input           matlab.ui.control.NumericEditField
        BalloonWeightLabel_8            matlab.ui.control.Label
        MetersLabel                     matlab.ui.control.Label
        Ground_Pressure_Input           matlab.ui.control.NumericEditField
        BalloonWeightLabel_7            matlab.ui.control.Label
        PascalsLabel                    matlab.ui.control.Label
        Ground_Temperature_Input        matlab.ui.control.NumericEditField
        BalloonWeightLabel_6            matlab.ui.control.Label
        KelvinLabel                     matlab.ui.control.Label
        Balloon_Drag_Coefficient_Input  matlab.ui.control.NumericEditField
        BalloonWeightLabel_5            matlab.ui.control.Label
        UnitlessLabel_2                 matlab.ui.control.Label
        Balloon_Filled_Diameter_Input   matlab.ui.control.NumericEditField
        BalloonWeightLabel_3            matlab.ui.control.Label
        MetersLabel_3                   matlab.ui.control.Label
        Balloon_Burst_Diameter_Input    matlab.ui.control.NumericEditField
        BalloonWeightLabel_2            matlab.ui.control.Label
        MetersLabel_4                   matlab.ui.control.Label
        KilogramsLabel                  matlab.ui.control.Label
        Balloon_Mass_Input              matlab.ui.control.NumericEditField
        BalloonWeightLabel              matlab.ui.control.Label
        ExitProgramButton               matlab.ui.control.Button
        ResetValuesButton               matlab.ui.control.Button
        RunSimulationButton             matlab.ui.control.Button
        Velocity_vs_Time_Plot_Imperial  matlab.ui.control.UIAxes
        Altitude_vs_Time_Plot_Imperial  matlab.ui.control.UIAxes
        Pressure_vs_Time_Plot_Imperial  matlab.ui.control.UIAxes
        Temperature_vs_Time_Plot_Imperial  matlab.ui.control.UIAxes
        Velocity_vs_Time_Plot_SI        matlab.ui.control.UIAxes
        Altitude_vs_Time_Plot_SI        matlab.ui.control.UIAxes
        Pressure_vs_Time_Plot_SI        matlab.ui.control.UIAxes
        Temperature_vs_Time_Plot_SI     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Variables I WILL NEED
        Altitude_Table
        Balloon_Velocity_Table
        Balloon_Diameter_Table
        Balloon_Volume_Table
        Density_Table
        Gravity_Table
        Pressure_Table
        Temperature_Table
        Time_Table
        Time_Apogee
        Time_Descent
        Time_Max_Reached


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function Run_Simulation_Button_Pushed(app, event)

            clc

            %% Run EVERYTHING Function :)

            % Note, this program uses regular, old ass SI units. So please
            % only plug in meters, kilograms, pascals, and kelvin into this
            % thing otherwise we are gonna have a terrible time :o

            % Had to add in this if-elseif statement after giving the
            % option to change displayed units

            if app.Hidden_Flipper.Value == 0

                % all good dont need to change :)

            elseif app.Hidden_Flipper.Value == 1

                app.Balloon_Mass_Input.Value = app.Balloon_Mass_Input.Value / 2.20462;
                app.Balloon_Max_Diameter_Print.Value = app.Balloon_Max_Diameter_Print.Value / 3.28084;
                app.Coldest_Temperature_Print.Value = (app.Coldest_Temperature_Print.Value - 32) * (5/9) + 273.15;
                app.Lowest_Pressure_Print.Value = app.Lowest_Pressure_Print.Value * 101300;
                app.Max_Altitude_Print.Value = app.Max_Altitude_Print.Value / 3.28084;

                app.Balloon_Mass_Input.Value = app.Balloon_Mass_Input.Value;
                app.Balloon_Burst_Diameter_Input.Value = app.Balloon_Burst_Diameter_Input.Value / 3.28084;
                app.Balloon_Filled_Diameter_Input.Value = app.Balloon_Filled_Diameter_Input.Value / 3.28084;

                app.Ground_Temperature_Input.Value = (app.Ground_Temperature_Input.Value - 32) * (5/9) + 273.15;
                app.Ground_Pressure_Input.Value = app.Ground_Pressure_Input.Value * 101300;
                app.Ground_Altitude_Input.Value = app.Ground_Altitude_Input.Value / 3.28084;

                app.Payload_Mass_Input.Value = app.Payload_Mass_Input.Value / 2.20462;
                app.Parachute_Area_Input.Value = app.Parachute_Area_Input.Value / 10.7639;

            end

            [app.Altitude_Table.Value,app.Balloon_Velocity_Table.Value,app.Density_Table,app.Gravity_Table.Value,app.Pressure_Table.Value,app.Temperature_Table.Value,app.Time_Table.Value,app.Balloon_Diameter_Table.Value,app.Balloon_Volume_Table.Value,app.Time_Apogee.Value,app.Time_Descent.Value,app.Time_Max_Reached.Value] = Calculate_Everything_Weather_Balloon_Simulation(app.Balloon_Mass_Input.Value,app.Balloon_Burst_Diameter_Input.Value,app.Balloon_Filled_Diameter_Input.Value,app.Balloon_Drag_Coefficient_Input.Value,app.Ground_Temperature_Input.Value,app.Ground_Pressure_Input.Value,app.Ground_Altitude_Input.Value,app.Payload_Mass_Input.Value,app.Parachute_Area_Input.Value,app.Parachute_Drag_Coefficient_Input.Value,app.Time_Step_Input.Value);

            %% Plot EVERYTHING

            if app.Hidden_Flipper.Value == 0

                % Velocity vs Time Plot (m/s vs hours) SI
                plot(app.Velocity_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Balloon_Velocity_Table.Value)

                % Altitude vs Time Plot (km vs hours) SI
                plot(app.Altitude_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Altitude_Table.Value/1000)

                % Pressure vs Time Plot (kPa vs hours) SI
                plot(app.Pressure_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Pressure_Table.Value/1000)

                % Temperature vs Time Plot (K vs hours) SI
                plot(app.Temperature_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Temperature_Table.Value)

            elseif app.Hidden_Flipper.Value == 1

                % Velocity vs Time Plot (m/s vs hours) Imperial
                plot(app.Velocity_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,app.Balloon_Velocity_Table.Value * 3.28084)

                % Altitude vs Time Plot (km vs hours) Imperial
                plot(app.Altitude_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,app.Altitude_Table.Value*3.28084)

                % Pressure vs Time Plot (kPa vs hours) Imperial
                plot(app.Pressure_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,app.Pressure_Table.Value/101300)

                % Temperature vs Time Plot (K vs hours) Imperial
                plot(app.Temperature_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,(app.Temperature_Table.Value - 273.15) * (9/5) + 32)


            end

            %% Print Important Values

            if app.Time_Max_Reached.Value == 1

                app.Total_Time_Print.Value = 0;
                app.Coldest_Temperature_Print.Value = 0;
                app.Lowest_Pressure_Print.Value = 0;
                app.Balloon_Max_Diameter_Print.Value = 0;
                app.Time_to_Apogee_Print.Value = 0;
                app.Time_to_Descend_Print.Value = 0;
                app.Max_Altitude_Print.Value = 0;
                app.Impact_Velocity_Print.Value = 0;


            else

                app.Total_Time_Print.Value = app.Time_Table.Value(end) / 3600;
                app.Coldest_Temperature_Print.Value = min(app.Temperature_Table.Value);
                app.Lowest_Pressure_Print.Value = min(app.Pressure_Table.Value);
                app.Balloon_Max_Diameter_Print.Value = max(app.Balloon_Diameter_Table.Value);
                app.Time_to_Apogee_Print.Value = app.Time_Apogee.Value / 3600;
                app.Time_to_Descend_Print.Value = (app.Time_Descent.Value-app.Time_Apogee.Value) / 3600;
                app.Max_Altitude_Print.Value = max(app.Altitude_Table.Value);
                app.Impact_Velocity_Print.Value = -1 * app.Balloon_Velocity_Table.Value(end);

            end

            %% Reveal Completed Label or Max Error Label

            if app.Time_Max_Reached.Value == 1

                app.MaxTimeError_Label.Visible = 'on';
                app.SimulationCompletedLabel.Visible = 'off';

            else

                app.MaxTimeError_Label.Visible = 'off';
                app.SimulationCompletedLabel.Visible = 'on';

            end

        end

        % Button pushed function: ResetValuesButton
        function Reset_Values_Button_Pushed(app, event)
                
                % Reset original values
                app.Balloon_Mass_Input.Value = 1.2;
                app.Balloon_Burst_Diameter_Input.Value = 10;
                app.Balloon_Filled_Diameter_Input.Value = 5;
                app.Ground_Temperature_Input.Value = 294.82;
                app.Ground_Pressure_Input.Value = 99085.73;
                app.Payload_Mass_Input.Value = 0.453592;

                % Reset Plots
                cla(app.Temperature_vs_Time_Plot_Imperial)
                cla(app.Velocity_vs_Time_Plot_Imperial)
                cla(app.Pressure_vs_Time_Plot_Imperial)
                cla(app.Altitude_vs_Time_Plot_Imperial)
                cla(app.Temperature_vs_Time_Plot_SI)
                cla(app.Velocity_vs_Time_Plot_SI)
                cla(app.Pressure_vs_Time_Plot_SI)
                cla(app.Altitude_vs_Time_Plot_SI)

                % Set Tables to Zero
                app.Altitude_Table.Value = 0;
                app.Balloon_Velocity_Table.Value = 0;
                app.Temperature_Table.Value = 0;
                app.Pressure_Table.Value = 0;
                app.Time_Table.Value = 0;

                % Reset Returned Values
                app.Time_to_Apogee_Print.Value = 0;
                app.Time_to_Descend_Print.Value = 0;
                app.Total_Time_Print.Value = 0;
                app.Balloon_Max_Diameter_Print.Value = 0;

                if app.Hidden_Flipper.Value == 0

                    app.Coldest_Temperature_Print.Value = 0;

                elseif app.Hidden_Flipper.Value == 1

                    app.Coldest_Temperature_Print.Value = (0 - 273.15) * (9/5) + 32;

                end
               
                app.Lowest_Pressure_Print.Value = 0;
                app.Max_Altitude_Print.Value = 0;

                % Hide Simulation Complete and Max Time Error Label

                app.SimulationCompletedLabel.Visible = 'off';
                app.MaxTimeError_Label.Visible = 'off';


        end

        % Button pushed function: ExitProgramButton
        function Exit_Program_Button_Pushed(app, event)
             closereq()                                 % Closes the GUI
        end

        % Button pushed function: 
        % Change_Between_SI_and_Imperial_Units_Button
        function Change_Between_SI_and_Imperial_Units_ButtonPushed(app, event)
            
            % This button will help change between SI units (Meters,
            % Kilograms, Pascals,etc.) and Imperial (Feet, Pounds,
            % atmospheres, etc.) for displaying purposes. It will also need
            % to make a switcher function for the code to know when to
            % convert units

            if app.Hidden_Flipper.Value == 0

                app.Hidden_Flipper.Value = app.Hidden_Flipper.Value + 1;

            elseif app.Hidden_Flipper.Value == 1

                app.Hidden_Flipper.Value = app.Hidden_Flipper.Value - 1;

            end

            % Code for Switching to SI Unit Lables and Graphs

            if app.Hidden_Flipper.Value == 0

                % Temprature Flipped
                app.Temperature_vs_Time_Plot_SI.Visible = "on";
                plot(app.Temperature_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Temperature_Table.Value)
                app.Temperature_vs_Time_Plot_Imperial.Visible = "off";
                cla(app.Temperature_vs_Time_Plot_Imperial)

                % Velocity Flipped
                app.Velocity_vs_Time_Plot_SI.Visible = "on";
                plot(app.Velocity_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Balloon_Velocity_Table.Value)
                app.Velocity_vs_Time_Plot_Imperial.Visible = "off";
                cla(app.Velocity_vs_Time_Plot_Imperial)

                % Pressure Flipped
                app.Pressure_vs_Time_Plot_SI.Visible = "on";
                plot(app.Pressure_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Pressure_Table.Value/1000)
                app.Pressure_vs_Time_Plot_Imperial.Visible = "off";
                cla(app.Pressure_vs_Time_Plot_Imperial)

                % Altitude Flipped
                app.Altitude_vs_Time_Plot_SI.Visible = "on";
                plot(app.Altitude_vs_Time_Plot_SI,app.Time_Table.Value/3600,app.Altitude_Table.Value/1000)
                app.Altitude_vs_Time_Plot_Imperial.Visible = "off";
                cla(app.Altitude_vs_Time_Plot_Imperial)

                % All SI Labels Flipped ON
                app.MetersLabel.Visible = 'on';
                app.MetersLabel_3.Visible = 'on';
                app.MetersLabel_4.Visible = 'on';
                app.MetersLabel_5.Visible = 'on';
                app.MetersLabel_6.Visible = 'on';
                app.Meters2Label.Visible = 'on';
                app.PascalsLabel.Visible = 'on';
                app.PascalsLabel_2.Visible = 'on';
                app.KelvinLabel.Visible = 'on';
                app.KelvinLabel_2.Visible = 'on';
                app.KilogramsLabel.Visible = 'on';
                app.KilogramsLabel_2.Visible = 'on';
                app.MeterspersecondLabel.Visible = 'on';


                % All Imperial Labels Flipped OFF
                app.FeetLabel.Visible = 'off';
                app.FeetLabel_2.Visible = 'off';
                app.FeetLabel_3.Visible = 'off';
                app.FeetLabel_4.Visible = 'off';
                app.FeetLabel_6.Visible = 'off';
                app.Feet2Label.Visible = 'off';
                app.AtmospheresLabel.Visible = 'off';
                app.AtmospheresLabel_2.Visible = 'off';
                app.FahrenheitLabel.Visible = 'off';
                app.FahrenheitLabel_2.Visible = 'off';
                app.PoundsLabel.Visible = 'off';
                app.PoundsLabel_2.Visible = 'off';
                app.FeetpersecondLabel.Visible = 'off';

                % Convert Populate Numbers into Correct units

                app.Balloon_Mass_Input.Value = app.Balloon_Mass_Input.Value / 2.20462;
                app.Balloon_Max_Diameter_Print.Value = app.Balloon_Max_Diameter_Print.Value / 3.28084;
                app.Coldest_Temperature_Print.Value = (app.Coldest_Temperature_Print.Value - 32) * (5/9) + 273.15;
                app.Lowest_Pressure_Print.Value = app.Lowest_Pressure_Print.Value * 101300;
                app.Max_Altitude_Print.Value = app.Max_Altitude_Print.Value / 3.28084;

                app.Balloon_Mass_Input.Value = app.Balloon_Mass_Input.Value;
                app.Balloon_Burst_Diameter_Input.Value = app.Balloon_Burst_Diameter_Input.Value / 3.28084;
                app.Balloon_Filled_Diameter_Input.Value = app.Balloon_Filled_Diameter_Input.Value / 3.28084;

                app.Ground_Temperature_Input.Value = (app.Ground_Temperature_Input.Value - 32) * (5/9) + 273.15;
                app.Ground_Pressure_Input.Value = app.Ground_Pressure_Input.Value * 101300;
                app.Ground_Altitude_Input.Value = app.Ground_Altitude_Input.Value / 3.28084;

                app.Payload_Mass_Input.Value = app.Payload_Mass_Input.Value / 2.20462;
                app.Parachute_Area_Input.Value = app.Parachute_Area_Input.Value / 10.7639;

                app.Impact_Velocity_Print.Value = app.Impact_Velocity_Print.Value / 3.281;

            end

            % Code for Switching to Imperial Unit Labels and Graphs

            if app.Hidden_Flipper.Value == 1

                % Temprature Flipped
                app.Temperature_vs_Time_Plot_SI.Visible = "off";
                cla(app.Temperature_vs_Time_Plot_SI)
                app.Temperature_vs_Time_Plot_Imperial.Visible = "on";
                plot(app.Temperature_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,(app.Temperature_Table.Value - 273.15) * (9/5) + 32)

                % Velocity Flipped
                app.Velocity_vs_Time_Plot_SI.Visible = "off";
                cla(app.Velocity_vs_Time_Plot_SI)
                app.Velocity_vs_Time_Plot_Imperial.Visible = "on";
                plot(app.Velocity_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,app.Balloon_Velocity_Table.Value * 3.28084)

                % Pressure Flipped
                app.Pressure_vs_Time_Plot_SI.Visible = "off";
                cla(app.Pressure_vs_Time_Plot_SI)
                app.Pressure_vs_Time_Plot_Imperial.Visible = "on";
                plot(app.Pressure_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,app.Pressure_Table.Value/101300)

                % Altitude Flipped
                app.Altitude_vs_Time_Plot_SI.Visible = "off";
                cla(app.Altitude_vs_Time_Plot_SI)
                app.Altitude_vs_Time_Plot_Imperial.Visible = "on";
                plot(app.Altitude_vs_Time_Plot_Imperial,app.Time_Table.Value/3600,app.Altitude_Table.Value*3.28084)

                % All SI Labels Flipped OFF
                app.MetersLabel.Visible = 'off';
                app.MetersLabel_3.Visible = 'off';
                app.MetersLabel_4.Visible = 'off';
                app.MetersLabel_5.Visible = 'off';
                app.MetersLabel_6.Visible = 'off';
                app.Meters2Label.Visible = 'off';
                app.PascalsLabel.Visible = 'off';
                app.PascalsLabel_2.Visible = 'off';
                app.KelvinLabel.Visible = 'off';
                app.KelvinLabel_2.Visible = 'off';
                app.KilogramsLabel.Visible = 'off';
                app.KilogramsLabel_2.Visible = 'off';
                app.MeterspersecondLabel.Visible = 'off';


                % All Imperial Labels Flipped ON
                app.FeetLabel.Visible = 'on';
                app.FeetLabel_2.Visible = 'on';
                app.FeetLabel_3.Visible = 'on';
                app.FeetLabel_4.Visible = 'on';
                app.FeetLabel_6.Visible = 'on';
                app.Feet2Label.Visible = 'on';
                app.AtmospheresLabel.Visible = 'on';
                app.AtmospheresLabel_2.Visible = 'on';
                app.FahrenheitLabel.Visible = 'on';
                app.FahrenheitLabel_2.Visible = 'on';
                app.PoundsLabel.Visible = 'on';
                app.PoundsLabel_2.Visible = 'on';
                app.FeetpersecondLabel.Visible = 'on';
                
                % Convert Populate Numbers into Correct units

                app.Balloon_Mass_Input.Value = app.Balloon_Mass_Input.Value * 2.20462;
                app.Balloon_Max_Diameter_Print.Value = app.Balloon_Max_Diameter_Print.Value * 3.28084;
                app.Coldest_Temperature_Print.Value = (app.Coldest_Temperature_Print.Value - 273.15) * (9/5) + 32;
                app.Lowest_Pressure_Print.Value = app.Lowest_Pressure_Print.Value / 101300;
                app.Max_Altitude_Print.Value = app.Max_Altitude_Print.Value * 3.28084;

                app.Balloon_Mass_Input.Value = app.Balloon_Mass_Input.Value;
                app.Balloon_Burst_Diameter_Input.Value = app.Balloon_Burst_Diameter_Input.Value * 3.28084;
                app.Balloon_Filled_Diameter_Input.Value = app.Balloon_Filled_Diameter_Input.Value * 3.28084;

                app.Ground_Temperature_Input.Value = (app.Ground_Temperature_Input.Value - 273.15) * (9/5) + 32;
                app.Ground_Pressure_Input.Value = app.Ground_Pressure_Input.Value / 101300;
                app.Ground_Altitude_Input.Value = app.Ground_Altitude_Input.Value * 3.28084;

                app.Payload_Mass_Input.Value = app.Payload_Mass_Input.Value * 2.20462;
                app.Parachute_Area_Input.Value = app.Parachute_Area_Input.Value * 10.7639;     

                app.Impact_Velocity_Print.Value = app.Impact_Velocity_Print.Value * 3.281;

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Temperature_vs_Time_Plot_SI = uiaxes(app.UIFigure);
            title(app.Temperature_vs_Time_Plot_SI, 'Temperature vs Time')
            xlabel(app.Temperature_vs_Time_Plot_SI, 'Time (Hours)')
            ylabel(app.Temperature_vs_Time_Plot_SI, 'Temperature (Kelvin)')
            zlabel(app.Temperature_vs_Time_Plot_SI, 'Z')
            app.Pressure_vs_Time_Plot_SI = uiaxes(app.UIFigure);
            title(app.Pressure_vs_Time_Plot_SI, 'Pressure vs Time')
            xlabel(app.Pressure_vs_Time_Plot_SI, 'Time (Hours)')
            ylabel(app.Pressure_vs_Time_Plot_SI, 'Pressure (kilo-Pascals)')
            zlabel(app.Pressure_vs_Time_Plot_SI, 'Z')
            app.Altitude_vs_Time_Plot_SI = uiaxes(app.UIFigure);
            title(app.Altitude_vs_Time_Plot_SI, 'Altitude vs Time')
            xlabel(app.Altitude_vs_Time_Plot_SI, 'Time (Hours)')
            ylabel(app.Altitude_vs_Time_Plot_SI, 'Height (km)')
            zlabel(app.Altitude_vs_Time_Plot_SI, 'Z')
            app.Velocity_vs_Time_Plot_SI = uiaxes(app.UIFigure);
            title(app.Velocity_vs_Time_Plot_SI, 'Velocity vs Time')
            xlabel(app.Velocity_vs_Time_Plot_SI, 'Time (Hours)')
            ylabel(app.Velocity_vs_Time_Plot_SI, 'Velocity (m/s)')
            zlabel(app.Velocity_vs_Time_Plot_SI, 'Z')
            app.Temperature_vs_Time_Plot_Imperial = uiaxes(app.UIFigure);
            title(app.Temperature_vs_Time_Plot_Imperial, 'Temperature vs Time')
            xlabel(app.Temperature_vs_Time_Plot_Imperial, 'Time (Hours)')
            ylabel(app.Temperature_vs_Time_Plot_Imperial, 'Temperature (Fahrenheit)')
            zlabel(app.Temperature_vs_Time_Plot_Imperial, 'Z')
            app.Temperature_vs_Time_Plot_Imperial.Visible = 'off';
            app.Pressure_vs_Time_Plot_Imperial = uiaxes(app.UIFigure);
            title(app.Pressure_vs_Time_Plot_Imperial, 'Pressure vs Time')
            xlabel(app.Pressure_vs_Time_Plot_Imperial, 'Time (Hours)')
            ylabel(app.Pressure_vs_Time_Plot_Imperial, 'Pressure (atmospheres)')
            zlabel(app.Pressure_vs_Time_Plot_Imperial, 'Z')
            app.Pressure_vs_Time_Plot_Imperial.Visible = 'off';
            app.Altitude_vs_Time_Plot_Imperial = uiaxes(app.UIFigure);
            title(app.Altitude_vs_Time_Plot_Imperial, 'Altitude vs Time')
            xlabel(app.Altitude_vs_Time_Plot_Imperial, 'Time (Hours)')
            ylabel(app.Altitude_vs_Time_Plot_Imperial, 'Height (ft)')
            zlabel(app.Altitude_vs_Time_Plot_Imperial, 'Z')
            app.Altitude_vs_Time_Plot_Imperial.Visible = 'off';
            app.Velocity_vs_Time_Plot_Imperial = uiaxes(app.UIFigure);
            title(app.Velocity_vs_Time_Plot_Imperial, 'Velocity vs Time')
            xlabel(app.Velocity_vs_Time_Plot_Imperial, 'Time (Hours)')
            ylabel(app.Velocity_vs_Time_Plot_Imperial, 'Velocity (ft/s)')
            zlabel(app.Velocity_vs_Time_Plot_Imperial, 'Z')
            app.Velocity_vs_Time_Plot_Imperial.Visible = 'off';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @Run_Simulation_Button_Pushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResetValuesButton = uibutton(app.UIFigure, 'push');
            app.ResetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_Values_Button_Pushed, true);
            app.ResetValuesButton.Text = 'Reset Values';
            app.ExitProgramButton = uibutton(app.UIFigure, 'push');
            app.ExitProgramButton.ButtonPushedFcn = createCallbackFcn(app, @Exit_Program_Button_Pushed, true);
            app.ExitProgramButton.Text = 'Exit Program';
            app.BalloonWeightLabel = uilabel(app.UIFigure);
            app.BalloonWeightLabel.Text = 'Balloon Mass';
            app.Balloon_Mass_Input = uieditfield(app.UIFigure, 'numeric');
            app.Balloon_Mass_Input.ValueDisplayFormat = '%.2f';
            app.Balloon_Mass_Input.Value = 1.2;
            app.KilogramsLabel = uilabel(app.UIFigure);
            app.KilogramsLabel.Text = 'Kilograms';
            app.MetersLabel_4 = uilabel(app.UIFigure);
            app.MetersLabel_4.Text = 'Meters';
            app.BalloonWeightLabel_2 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_2.Text = 'Balloon Burst Diameter';
            app.Balloon_Burst_Diameter_Input = uieditfield(app.UIFigure, 'numeric');
            app.Balloon_Burst_Diameter_Input.ValueDisplayFormat = '%.2f';
            app.Balloon_Burst_Diameter_Input.Value = 10;
            app.MetersLabel_3 = uilabel(app.UIFigure);
            app.MetersLabel_3.Text = 'Meters';
            app.BalloonWeightLabel_3 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_3.Text = 'Balloon Filled Diameter';
            app.Balloon_Filled_Diameter_Input = uieditfield(app.UIFigure, 'numeric');
            app.Balloon_Filled_Diameter_Input.ValueDisplayFormat = '%.2f';
            app.Balloon_Filled_Diameter_Input.Value = 5;
            app.UnitlessLabel_2 = uilabel(app.UIFigure);
            app.UnitlessLabel_2.Text = 'Unitless';
            app.BalloonWeightLabel_5 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_5.Text = 'Balloon Drag Coefficient';
            app.Balloon_Drag_Coefficient_Input = uieditfield(app.UIFigure, 'numeric');
            app.Balloon_Drag_Coefficient_Input.ValueDisplayFormat = '%.2f';
            app.Balloon_Drag_Coefficient_Input.Value = 1;
            app.KelvinLabel = uilabel(app.UIFigure);
            app.KelvinLabel.Text = 'Kelvin';
            app.BalloonWeightLabel_6 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_6.Text = 'Ground Temperature';
            app.Ground_Temperature_Input = uieditfield(app.UIFigure, 'numeric');
            app.Ground_Temperature_Input.ValueDisplayFormat = '%.2f';
            app.Ground_Temperature_Input.Value = 294.82;
            app.PascalsLabel = uilabel(app.UIFigure);
            app.PascalsLabel.Text = 'Pascals';
            app.BalloonWeightLabel_7 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_7.Text = 'Ground Pressure';
            app.Ground_Pressure_Input = uieditfield(app.UIFigure, 'numeric');
            app.Ground_Pressure_Input.ValueDisplayFormat = '%.2f';
            app.Ground_Pressure_Input.Value = 99085.73;
            app.MetersLabel = uilabel(app.UIFigure);
            app.MetersLabel.Text = 'Meters';
            app.BalloonWeightLabel_8 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_8.Text = 'Ground Altitude';
            app.Ground_Altitude_Input = uieditfield(app.UIFigure, 'numeric');
            app.Ground_Altitude_Input.ValueDisplayFormat = '%.2f';
            app.Ground_Altitude_Input.Value = 238;
            app.KilogramsLabel_2 = uilabel(app.UIFigure);
            app.KilogramsLabel_2.Text = 'Kilograms';
            app.BalloonWeightLabel_9 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_9.Text = 'Payload Mass';
            app.Payload_Mass_Input = uieditfield(app.UIFigure, 'numeric');
            app.Payload_Mass_Input.ValueDisplayFormat = '%.2f';
            app.Payload_Mass_Input.Value = 0.453592;
            app.Meters2Label = uilabel(app.UIFigure);
            app.Meters2Label.Text = 'Meters^2';
            app.BalloonWeightLabel_10 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_10.Text = 'Parachute Area';
            app.Parachute_Area_Input = uieditfield(app.UIFigure, 'numeric');
            app.Parachute_Area_Input.ValueDisplayFormat = '%.2f';
            app.Parachute_Area_Input.Value = 1;
            app.UnitlessLabel = uilabel(app.UIFigure);
            app.UnitlessLabel.Text = 'Unitless';
            app.BalloonWeightLabel_11 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_11.Text = 'Parachute Drag Coefficient';
            app.Parachute_Drag_Coefficient_Input = uieditfield(app.UIFigure, 'numeric');
            app.Parachute_Drag_Coefficient_Input.ValueDisplayFormat = '%.2f';
            app.Parachute_Drag_Coefficient_Input.Value = 1.75;
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Text = 'Seconds';
            app.BalloonWeightLabel_12 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_12.Text = 'Time Step';
            app.Time_Step_Input = uieditfield(app.UIFigure, 'numeric');
            app.Time_Step_Input.ValueDisplayFormat = '%.2f';
            app.Time_Step_Input.Value = 0.05;
            app.BalloonParametersLabel = uilabel(app.UIFigure);
            app.BalloonParametersLabel.Text = 'Balloon Parameters';
            app.GroundReadingsLabel = uilabel(app.UIFigure);
            app.GroundReadingsLabel.Text = 'Ground Readings';
            app.PayloadParametersLabel = uilabel(app.UIFigure);
            app.PayloadParametersLabel.Text = 'Payload Parameters';
            app.ImportantValuesFromSimulationLabel = uilabel(app.UIFigure);
            app.ImportantValuesFromSimulationLabel.Text = 'Important Values From Simulation';
            app.MiscellaneousParametersLabel = uilabel(app.UIFigure);
            app.MiscellaneousParametersLabel.Text = 'Miscellaneous Parameters';
            app.HoursLabel = uilabel(app.UIFigure);
            app.HoursLabel.Text = 'Hours';
            app.BalloonWeightLabel_13 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_13.Text = 'Time to Apogee ';
            app.Time_to_Apogee_Print = uieditfield(app.UIFigure, 'numeric');
            app.Time_to_Apogee_Print.ValueDisplayFormat = '%.2f';
            app.HoursLabel_2 = uilabel(app.UIFigure);
            app.HoursLabel_2.Text = 'Hours';
            app.BalloonWeightLabel_14 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_14.Text = 'Time to Descend';
            app.Time_to_Descend_Print = uieditfield(app.UIFigure, 'numeric');
            app.Time_to_Descend_Print.ValueDisplayFormat = '%.2f';
            app.HoursLabel_3 = uilabel(app.UIFigure);
            app.HoursLabel_3.Text = 'Hours';
            app.BalloonWeightLabel_15 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_15.Text = 'Total Time';
            app.Total_Time_Print = uieditfield(app.UIFigure, 'numeric');
            app.Total_Time_Print.ValueDisplayFormat = '%.2f';
            app.MetersLabel_5 = uilabel(app.UIFigure);
            app.MetersLabel_5.Text = 'Meters';
            app.BalloonWeightLabel_16 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_16.Text = 'Balloon Max Diameter';
            app.Balloon_Max_Diameter_Print = uieditfield(app.UIFigure, 'numeric');
            app.Balloon_Max_Diameter_Print.ValueDisplayFormat = '%.2f';
            app.KelvinLabel_2 = uilabel(app.UIFigure);
            app.KelvinLabel_2.Text = 'Kelvin';
            app.BalloonWeightLabel_17 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_17.Text = 'Coldest Temperature';
            app.Coldest_Temperature_Print = uieditfield(app.UIFigure, 'numeric');
            app.Coldest_Temperature_Print.ValueDisplayFormat = '%.2f';
            app.PascalsLabel_2 = uilabel(app.UIFigure);
            app.PascalsLabel_2.Text = 'Pascals';
            app.BalloonWeightLabel_18 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_18.Text = 'Lowest Pressure';
            app.Lowest_Pressure_Print = uieditfield(app.UIFigure, 'numeric');
            app.Lowest_Pressure_Print.ValueDisplayFormat = '%.4f';
            app.MetersLabel_6 = uilabel(app.UIFigure);
            app.MetersLabel_6.Text = 'Meters';
            app.BalloonWeightLabel_19 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_19.Text = 'Max Altitude';
            app.Max_Altitude_Print = uieditfield(app.UIFigure, 'numeric');
            app.Max_Altitude_Print.ValueDisplayFormat = '%.2f';
            app.Change_Between_SI_and_Imperial_Units_Button = uibutton(app.UIFigure, 'push');
            app.Change_Between_SI_and_Imperial_Units_Button.ButtonPushedFcn = createCallbackFcn(app, @Change_Between_SI_and_Imperial_Units_ButtonPushed, true);
            app.Change_Between_SI_and_Imperial_Units_Button.Text = 'Change Between SI and Imperial Units';
            app.BalloonWeightLabel_20 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_20.Visible = 'off';
            app.BalloonWeightLabel_20.Text = 'Flipper';
            app.Hidden_Flipper = uieditfield(app.UIFigure, 'numeric');
            app.Hidden_Flipper.ValueDisplayFormat = '%0.0f';
            app.Hidden_Flipper.Editable = 'off';
            app.Hidden_Flipper.Visible = 'off';
            app.FeetLabel = uilabel(app.UIFigure);
            app.FeetLabel.Visible = 'off';
            app.FeetLabel.Text = 'Feet';
            app.FahrenheitLabel = uilabel(app.UIFigure);
            app.FahrenheitLabel.Visible = 'off';
            app.FahrenheitLabel.Text = 'Fahrenheit';
            app.AtmospheresLabel = uilabel(app.UIFigure);
            app.AtmospheresLabel.Visible = 'off';
            app.AtmospheresLabel.Text = 'Atmospheres';
            app.FeetLabel_2 = uilabel(app.UIFigure);
            app.FeetLabel_2.Visible = 'off';
            app.FeetLabel_2.Text = 'Feet';
            app.FeetLabel_3 = uilabel(app.UIFigure);
            app.FeetLabel_3.Visible = 'off';
            app.FeetLabel_3.Text = 'Feet';
            app.FeetLabel_4 = uilabel(app.UIFigure);
            app.FeetLabel_4.Visible = 'off';
            app.FeetLabel_4.Text = 'Feet';
            app.FahrenheitLabel_2 = uilabel(app.UIFigure);
            app.FahrenheitLabel_2.Visible = 'off';
            app.FahrenheitLabel_2.Text = 'Fahrenheit';
            app.AtmospheresLabel_2 = uilabel(app.UIFigure);
            app.AtmospheresLabel_2.Visible = 'off';
            app.AtmospheresLabel_2.Text = 'Atmospheres';
            app.FeetLabel_6 = uilabel(app.UIFigure);
            app.FeetLabel_6.Visible = 'off';
            app.FeetLabel_6.Text = 'Feet';
            app.Feet2Label = uilabel(app.UIFigure);
            app.Feet2Label.Visible = 'off';
            app.Feet2Label.Text = 'Feet^2';
            app.PoundsLabel = uilabel(app.UIFigure);
            app.PoundsLabel.Visible = 'off';
            app.PoundsLabel.Text = 'Pounds';
            app.PoundsLabel_2 = uilabel(app.UIFigure);
            app.PoundsLabel_2.Visible = 'off';
            app.PoundsLabel_2.Text = 'Pounds';
            app.SimulationCompletedLabel = uilabel(app.UIFigure);
            app.SimulationCompletedLabel.Visible = 'off';
            app.SimulationCompletedLabel.Text = 'Simulation Completed!';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'High-Altitude Weather Balloon Test Flight Simulation Code';
            app.MaxTimeError_Label = uilabel(app.UIFigure);
            app.MaxTimeError_Label.Visible = 'off';
            app.MaxTimeError_Label.Text = 'Balloon will take >3 hours to max altitude';
            app.MeterspersecondLabel = uilabel(app.UIFigure);
            app.MeterspersecondLabel.Text = 'Meters per second';
            app.FeetpersecondLabel = uilabel(app.UIFigure);
            app.FeetpersecondLabel.Visible = 'off';
            app.FeetpersecondLabel.Text = 'Feet per second';
            app.BalloonWeightLabel_21 = uilabel(app.UIFigure);
            app.BalloonWeightLabel_21.Text = 'Impact Velocity';
            app.Impact_Velocity_Print = uieditfield(app.UIFigure, 'numeric');
            app.Impact_Velocity_Print.ValueDisplayFormat = '%.2f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wealther_Balloon_Simulation_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end